classdef gui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImageSelectorTab                matlab.ui.container.Tab
        ChooseImageDropDown             matlab.ui.control.DropDown
        ChooseImageDropDownLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        HistogramTab                    matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ShowHistogramButton             matlab.ui.control.Button
        histogram_3                     matlab.ui.control.UIAxes
        histogram_2                     matlab.ui.control.UIAxes
        histogram_1                     matlab.ui.control.UIAxes
        ImageEnhancementTab             matlab.ui.container.Tab
        histo_6                         matlab.ui.control.Label
        histo_5                         matlab.ui.control.Label
        histo_4                         matlab.ui.control.Label
        histo_3                         matlab.ui.control.Label
        histo_2                         matlab.ui.control.Label
        histo_1                         matlab.ui.control.Label
        label_2                         matlab.ui.control.Label
        label_1                         matlab.ui.control.Label
        formula                         matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        param_2                         matlab.ui.control.NumericEditField
        param_1                         matlab.ui.control.NumericEditField
        ChooseEnhancementDropDown       matlab.ui.control.DropDown
        ChooseEnhancementDropDownLabel  matlab.ui.control.Label
        img_2                           matlab.ui.control.UIAxes
        img_1                           matlab.ui.control.UIAxes
        h_after_3                       matlab.ui.control.UIAxes
        h_after_2                       matlab.ui.control.UIAxes
        h_after_1                       matlab.ui.control.UIAxes
        h_before_3                      matlab.ui.control.UIAxes
        h_before_2                      matlab.ui.control.UIAxes
        h_before_1                      matlab.ui.control.UIAxes
        HistogramEqualizationTab        matlab.ui.container.Tab
        StartHisteqButton               matlab.ui.control.Button
        image_after                     matlab.ui.control.UIAxes
        image_before                    matlab.ui.control.UIAxes
        h_6                             matlab.ui.control.UIAxes
        h_5                             matlab.ui.control.UIAxes
        h_4                             matlab.ui.control.UIAxes
        h_3                             matlab.ui.control.UIAxes
        h_2                             matlab.ui.control.UIAxes
        h_1                             matlab.ui.control.UIAxes
        HistogramSpecificationTab       matlab.ui.container.Tab
    end

    
    properties (Access = public)
        selected_image % Description
        image_title % image title
        selected_enhancement % Description
        selected_param_1 % Description
        selected_param_2 % Description
    end


    methods (Access = public)

        % No. 1 - Getting the component
        function [c] = histogram_calculation(app,I)
            b = size(I);
            I = double(I);
            c = zeros(1,256);
            for i=1 : b(1)
                for j=1 : b(2)
                    for k = 0 : 255
                         if I(i,j) == k
                             c(k+1) = c(k+1) +1;
                         end
                    end
                end
            end
            
        
        end
        
        % No. 1 - Bar Use Case
        function [histogram] = histogram_bar(app,I)
            histogram = app.histogram_calculation(I);
            bar(histogram, 'BarWidth', 1, 'FaceColor', 'b');
        end
        
        % No. 1 - Show Histogram with subplot
        function [n] = histogram_show(app,I)
            [~,~,n] = size(I);
            if n == 3
                r1 = I(:,:,1);
                g1 = I(:,:,2);
                b1 = I(:,:,3);
        
                subplot(1,3,1),app.histogram_bar(r1),title('Red');
                subplot(1,3,2),app.histogram_bar(g1),title('Green');
                subplot(1,3,3),app.histogram_bar(b1),title('Blue');
            else
                subplot(1,1,1),app.histogram_bar(I),title('Grayscale');
        
            end
        
        end   

        % No. 2.a - image brightening
        function [ s ] = makebright(app,r, a, b) 
           s = r*a + b; 
           s(s > 255) = 255;
           %subplot(1,2,1),imshow(r),title("Original Image")
           %subplot(1,2,2),imshow(s),title("Image after Transformation")
        end
        
        % No. 2.b - Transformasi log (𝑠 = 𝑐 𝑙𝑜𝑔(1 + 𝑟), 𝑐 dan r konstanta
        % parameter input dari pengguna
        function [ s ] = logTransform(app,r, c) 
           r_double = double(r)/255;
           s = c*log(1+(r_double)); 
           %subplot(1,2,1),imshow(r),title("Original Image")
           %subplot(1,2,2),imshow(s),title("Image after Log Transformation")
        end
        
        % No. 2.c - Transformasi pangkat (𝑠 = 𝑐𝑟^y, 𝑐 dan 𝛾 adalah parameter input dari pengguna)
        function [ s ] = powerTransform(app,r, c,y) 
            s = c*power(double(r)/255,double(y));
           %subplot(1,2,1),imshow(r),title("Original Image")
           %subplot(1,2,2),imshow(s),title("Image after Power Transformation")
        end
        
        % No. 2.d - Contrast Stretching
        function [ s ] = contrastStretching(app,r, rmin, rmax) 
           s = (r - rmin).*(255/(rmax - rmin)); 
           %subplot(1,2,1),imshow(r),title("Original Image")
           %subplot(1,2,2),imshow(s),title("Image after Power Transformation")
        end

        % No. 3 Histogram Equalization

        function [ result_image] = equalization(app,input_image) 
            
            [rows,columns,~] = size(input_image);
            result_image = uint8(zeros(rows,columns));
            pixel_number = rows*columns;
            frequency = zeros(256,1);
            pdf = zeros(256,1);
            cdf = zeros(256,1);
            cummulative = zeros(256,1);
            outpic = zeros(256,1);
            for i = 1:1:rows
                for j = 1:1:columns
                    val = input_image(i,j);
                    frequency(val+1) = frequency(val+1)+1;
                    pdf(val+1) = frequency(val+1)/pixel_number;
                end
            end
            sum =0 ;
            %we want the 256 - 1 that's why we initailzed the intensity_level with 255
            %instead of 256
            intensity_level = 255;
            
            for i = 1:1:size(pdf)
                sum =sum +frequency(i);
                cummulative(i) = sum;
                cdf(i) = cummulative(i)/ pixel_number;
                outpic(i) = round(cdf(i) * intensity_level);
            end
            
            
            for i = 1:1:rows
                for j = 1:1:columns
                    result_image(i,j) = outpic(input_image(i,j) + 1);
                end
            end
            
        end
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            I = imread('image/Fig0222(a)(face).tif');
            app.UIAxes2.Title.String = "Lenna Black & White"
            imshow(I, "Parent", app.UIAxes2)
            app.selected_image = I;
   

            app.formula.Text = "s = r*a + b, masukan a dan b"
            app.label_1.Text = "a"
            app.label_2.Text = "b"

            app.selected_enhancement = "Brightening"
            app.selected_param_1 = 0
            app.selected_param_2 = 0

            app.img_2.Title.String = "Image Before"
            app.img_2.Title.String = "Image After"


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title

            
            switch selectedTab
                case "Image Selector"
                    I = app.selected_image
                    
                    app.UIAxes2.Title.String = app.image_title
                    imshow(I, "Parent", app.UIAxes2)
                    
            end
        end

        % Value changed function: ChooseImageDropDown
        function ChooseImageDropDownValueChanged(app, event)
            value = app.ChooseImageDropDown.Value;

            switch value
                case "Image 1"
                    app.selected_image = imread('image/Fig0222(a)(face).tif');
                    app.image_title = "Lenna Black & White Zoomed"
                    %imshow(I, "Parent", app.UIAxes2)
                case "Image 2"
                    app.selected_image  = imread('image/Fig0630(01)(strawberries_fullcolor).tif')
                    app.image_title = "Srawberries Full Color"
                    %imshow(I, "Parent", app.UIAxes2)

                case "Image 3"
                    app.selected_image  = imread('image/Fig0320(2)(2nd_from_top).tif')
                    app.image_title = "Beans Black and White - Low Contrast"
                    %imshow(I, "Parent", app.UIAxes2)
                case "Image 4"
                    app.selected_image = imread('image/Fig0646(a)(lenna_original_RGB).tif')
                    app.image_title = "Lenna Full Color"
                    %imshow(I, "Parent", app.UIAxes2)

             
            end
            app.param_1.Value = 0
            app.param_2.Value = 0
            app.UIAxes2.Title.String = app.image_title
            imshow(app.selected_image, "Parent", app.UIAxes2)
            
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            I = app.selected_image;
            [~,~,n] = size(I);
            if n == 3
                r1 = I(:,:,1);
                g1 = I(:,:,2);
                b1 = I(:,:,3);
        
               histogram_r = app.histogram_calculation(r1);
               histogram_g = app.histogram_calculation(g1);
               histogram_b = app.histogram_calculation(b1);
               bar(app.histogram_1,histogram_r, 'BarWidth', 1, 'FaceColor', 'b');
               bar(app.histogram_2,histogram_g, 'BarWidth', 1, 'FaceColor', 'b');
               bar(app.histogram_3,histogram_b, 'BarWidth', 1, 'FaceColor', 'b');

               app.Label.Text = "Red Histogram"
               app.Label_2.Text = "Blue Histogram"
               app.Label_3.Text = "Green Histogram"
             
            else
               histogram_gray = app.histogram_calculation(I);
               bar(app.histogram_1,0, 'BarWidth', 0, 'FaceColor', 'b');
               app.histogram_1.Visible = 'off';
               bar(app.histogram_2,0, 'BarWidth', 0, 'FaceColor', 'b');
               app.histogram_2.Visible = 'off';
               bar(app.histogram_3,histogram_gray, 'BarWidth', 1, 'FaceColor', 'b');

               app.Label.Text = ""
               app.Label_2.Text = ""
               app.Label_3.Text = "Grayscale Histogram"


            end
        end

        % Value changed function: ChooseEnhancementDropDown
        function ChooseEnhancementDropDownValueChanged(app, event)
            value = app.ChooseEnhancementDropDown.Value;
            

            switch value
                case "Brightening"
                    app.formula.Text = "s = r*a + b, masukan a dan b"
                    app.label_1.Text = "a"
                    app.label_2.Text = "b"
                    app.param_1.Value = 0
                    app.param_2.Value = 0
                    app.selected_enhancement = "Brightening"
   
   
                case "Log Transformation"
                    app.formula.Text = "𝑠 = 𝑐 𝑙𝑜𝑔(1 + 𝑟), masukan 𝑐"
                    app.label_1.Text = "𝑐"
                    app.label_2.Text = "-"
                    app.param_1.Value = 0
                    app.param_2.Value = 0
                    app.selected_enhancement = "Log Transformation"

                case "Power Transformation"
                    app.formula.Text = "𝑠 = 𝑐𝑟^y, masukan 𝑐 dan 𝛾 "
                    app.label_1.Text = "𝑐"
                    app.label_2.Text = "𝛾"
                    app.param_1.Value = 0
                    app.param_2.Value = 0
                    app.selected_enhancement = "Power Transformation"

                case "Contrast Stretching"
                    app.formula.Text = "masukan rmin dan rmax"
                    app.label_1.Text = "rmin"
                    app.label_2.Text = "rmax"
                    app.param_1.Value = 0
                    app.param_2.Value = 0
                    app.selected_enhancement = "Contrast Stretching"
                                 
            end


        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            value = app.selected_enhancement;            

            if value == "Brightening"
                I = app.selected_image
                I_out = app.makebright(app.selected_image, app.selected_param_1, app.selected_param_2) 
                imshow(I, "Parent", app.img_1)
                imshow(I_out, "Parent", app.img_2)
    
                [~,~,n] = size(I);
                if n == 3
                    r1 = I(:,:,1);
                    g1 = I(:,:,2);
                    b1 = I(:,:,3);

                    r2 = I_out(:,:,1);
                    g2 = I_out(:,:,2);
                    b2 = I_out(:,:,3);
            
                   histogram_r = app.histogram_calculation(r1);
                   histogram_g = app.histogram_calculation(g1);
                   histogram_b = app.histogram_calculation(b1);

                   histogram_r2 = app.histogram_calculation(r2);
                   histogram_g2 = app.histogram_calculation(g2);
                   histogram_b2 = app.histogram_calculation(b2);

                   bar(app.h_before_1,histogram_r, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,histogram_g, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,histogram_b, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_r2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,histogram_g2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,histogram_b2, 'BarWidth', 1, 'FaceColor', 'b');

                   app.histo_1.Text = "Histogram Red Before"
                   app.histo_2.Text = "Histogram Gren Before"
                   app.histo_3.Text = "Histogram Blue Before"
                   app.histo_4.Text = "Histogram Red After"
                   app.histo_5.Text = "Histogram Green After"
                   app.histo_6.Text = "Histogram Blue After"


                else

                   histogram_gray = app.histogram_calculation(I);
                   histogram_gray_2 = app.histogram_calculation(I_out);
                   
                   %{
                   bar(app.histogram_1,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_1.Visible = 'off';
                   bar(app.histogram_2,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_2.Visible = 'off';

                   %}
                   bar(app.h_before_1,histogram_gray, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,0, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_gray_2 ,'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,0, 'BarWidth', 1, 'FaceColor', 'b');

                   app.histo_1.Text = "Histogram Grayscale Before"
                   app.histo_2.Text = "-"
                   app.histo_3.Text = "-"
                   app.histo_4.Text = "Histogram Grayscale After"
                   app.histo_5.Text = "-"
                   app.histo_6.Text = "-"


                end

            elseif value == "Log Transformation"
                I = app.selected_image
                I_out = app.logTransform(app.selected_image, app.selected_param_1) 
                imshow(I, "Parent", app.img_1)
                imshow(I_out, "Parent", app.img_2)

                [~,~,n] = size(I);
                if n == 3
                    r1 = I(:,:,1);
                    g1 = I(:,:,2);
                    b1 = I(:,:,3);

                    r2 = I_out(:,:,1);
                    g2 = I_out(:,:,2);
                    b2 = I_out(:,:,3);
            
                   histogram_r = app.histogram_calculation(r1);
                   histogram_g = app.histogram_calculation(g1);
                   histogram_b = app.histogram_calculation(b1);

                   histogram_r2 = app.histogram_calculation(r2);
                   histogram_g2 = app.histogram_calculation(g2);
                   histogram_b2 = app.histogram_calculation(b2);

                   bar(app.h_before_1,histogram_r, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,histogram_g, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,histogram_b, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_r2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,histogram_g2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,histogram_b2, 'BarWidth', 1, 'FaceColor', 'b');

                   app.histo_1.Text = "Histogram Red Before"
                   app.histo_2.Text = "Histogram Gren Before"
                   app.histo_3.Text = "Histogram Blue Before"
                   app.histo_4.Text = "Histogram Red After"
                   app.histo_5.Text = "Histogram Green After"
                   app.histo_6.Text = "Histogram Blue After"


                else

                   histogram_gray = app.histogram_calculation(I);
                   histogram_gray_2 = app.histogram_calculation(I_out);
                   
                   %{
                   bar(app.histogram_1,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_1.Visible = 'off';
                   bar(app.histogram_2,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_2.Visible = 'off';

                   %}
                   bar(app.h_before_1,histogram_gray, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,0, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_gray_2 ,'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,0, 'BarWidth', 1, 'FaceColor', 'b');

                   app.histo_1.Text = "Histogram Grayscale Before"
                   app.histo_2.Text = "-"
                   app.histo_3.Text = "-"
                   app.histo_4.Text = "Histogram Grayscale After"
                   app.histo_5.Text = "-"
                   app.histo_6.Text = "-"
    
  
                end


            elseif value == "Power Transformation"
                I = app.selected_image
                I_out = app.powerTransform(app.selected_image, app.selected_param_1, app.selected_param_2) 
                imshow(I, "Parent", app.img_1)
                imshow(I_out, "Parent", app.img_2)

                [~,~,n] = size(I);
                if n == 3
                    r1 = I(:,:,1);
                    g1 = I(:,:,2);
                    b1 = I(:,:,3);

                    r2 = I_out(:,:,1);
                    g2 = I_out(:,:,2);
                    b2 = I_out(:,:,3);
            
                   histogram_r = app.histogram_calculation(r1);
                   histogram_g = app.histogram_calculation(g1);
                   histogram_b = app.histogram_calculation(b1);

                   histogram_r2 = app.histogram_calculation(r2);
                   histogram_g2 = app.histogram_calculation(g2);
                   histogram_b2 = app.histogram_calculation(b2);

                   bar(app.h_before_1,histogram_r, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,histogram_g, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,histogram_b, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_r2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,histogram_g2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,histogram_b2, 'BarWidth', 1, 'FaceColor', 'b');

                   app.histo_1.Text = "Histogram Red Before"
                   app.histo_2.Text = "Histogram Gren Before"
                   app.histo_3.Text = "Histogram Blue Before"
                   app.histo_4.Text = "Histogram Red After"
                   app.histo_5.Text = "Histogram Green After"
                   app.histo_6.Text = "Histogram Blue After"


                else

                   histogram_gray = app.histogram_calculation(I);
                   histogram_gray_2 = app.histogram_calculation(I_out);
                   
                   %{
                   bar(app.histogram_1,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_1.Visible = 'off';
                   bar(app.histogram_2,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_2.Visible = 'off';

                   %}
                   bar(app.h_before_1,histogram_gray, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,0, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_gray_2 ,'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,0, 'BarWidth', 1, 'FaceColor', 'b');
                   
                   app.histo_1.Text = "Histogram Grayscale Before"
                   app.histo_2.Text = "-"
                   app.histo_3.Text = "-"
                   app.histo_4.Text = "Histogram Grayscale After"
                   app.histo_5.Text = "-"
                   app.histo_6.Text = "-"
    
  
                end


            elseif value ==  "Contrast Stretching"
                I = app.selected_image
                I_out = app.contrastStretching(app.selected_image, app.selected_param_1, app.selected_param_2) 
                imshow(I, "Parent", app.img_1)
                imshow(I_out, "Parent", app.img_2)

                [~,~,n] = size(I);
                if n == 3
                    r1 = I(:,:,1);
                    g1 = I(:,:,2);
                    b1 = I(:,:,3);

                    r2 = I_out(:,:,1);
                    g2 = I_out(:,:,2);
                    b2 = I_out(:,:,3);
            
                   histogram_r = app.histogram_calculation(r1);
                   histogram_g = app.histogram_calculation(g1);
                   histogram_b = app.histogram_calculation(b1);

                   histogram_r2 = app.histogram_calculation(r2);
                   histogram_g2 = app.histogram_calculation(g2);
                   histogram_b2 = app.histogram_calculation(b2);

                   bar(app.h_before_1,histogram_r, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,histogram_g, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,histogram_b, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_r2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,histogram_g2, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,histogram_b2, 'BarWidth', 1, 'FaceColor', 'b');

                   app.histo_1.Text = "Histogram Red Before"
                   app.histo_2.Text = "Histogram Gren Before"
                   app.histo_3.Text = "Histogram Blue Before"
                   app.histo_4.Text = "Histogram Red After"
                   app.histo_5.Text = "Histogram Green After"
                   app.histo_6.Text = "Histogram Blue After"


                else

                   histogram_gray = app.histogram_calculation(I);
                   histogram_gray_2 = app.histogram_calculation(I_out);
                   
                   %{
                   bar(app.histogram_1,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_1.Visible = 'off';
                   bar(app.histogram_2,0, 'BarWidth', 0, 'FaceColor', 'b');
                   app.histogram_2.Visible = 'off';

                   %}
                   bar(app.h_before_1,histogram_gray, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_before_3,0, 'BarWidth', 1, 'FaceColor', 'b');
    

                   bar(app.h_after_1,histogram_gray_2 ,'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_2,0, 'BarWidth', 1, 'FaceColor', 'b');
                   bar(app.h_after_3,0, 'BarWidth', 1, 'FaceColor', 'b');

                   app.histo_1.Text = "Histogram Grayscale Before"
                   app.histo_2.Text = "-"
                   app.histo_3.Text = "-"
                   app.histo_4.Text = "Histogram Grayscale After"
                   app.histo_5.Text = "-"
                   app.histo_6.Text = "-"
    
  
                end


            end

        end

        % Value changed function: param_1
        function param_1ValueChanged(app, event)
            app.selected_param_1 = app.param_1.Value;
            
        end

        % Value changed function: param_2
        function param_2ValueChanged(app, event)
            app.selected_param_2 = app.param_2.Value;
            
        end

        % Button pushed function: StartHisteqButton
        function StartHisteqButtonPushed(app, event)
            I = app.selected_image
            
            [~,~,n] = size(I);
            if n == 3
                r1 = I(:,:,1);
                g1 = I(:,:,2);
                b1 = I(:,:,3);

                histogram_r = app.histogram_calculation(r1);
                histogram_g = app.histogram_calculation(g1);
                histogram_b = app.histogram_calculation(b1);
        
                histogram_r2 = equalization(r1);
                histogram_g2 = equalization(g1);
                histogram_b2 = equalization(b1);
        
                I_out = cat(3,r,g,b);

                imshow(I, "Parent", app.image_before)
                imshow(I_out, "Parent", app.image_after)

                bar(app.h_1,histogram_r, 'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_2,histogram_g, 'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_3,histogram_b, 'BarWidth', 1, 'FaceColor', 'b');


                bar(app.h_4,histogram_r2, 'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_5,histogram_g2, 'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_6,histogram_b2, 'BarWidth', 1, 'FaceColor', 'b');

                app.h_1.Title = "Histogram Red Before"
                app.histo_2.Text = "Histogram Gren Before"
                app.histo_3.Text = "Histogram Blue Before"
                app.histo_4.Text = "Histogram Red After"
                app.histo_5.Text = "Histogram Green After"
                app.histo_6.Text = "Histogram Blue After"


            else
        
                bar(app.h_1,0, 'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_2,histogram_gray, 'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_3,0, 'BarWidth', 1, 'FaceColor', 'b');


                bar(app.h_4,0 ,'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_5,histogram_gray_2, 'BarWidth', 1, 'FaceColor', 'b');
                bar(app.h_6,0, 'BarWidth', 1, 'FaceColor', 'b');

                app.histo_1.Text = "Histogram Grayscale Before"
                app.histo_2.Text = "-"
                app.histo_3.Text = "-"
                app.histo_4.Text = "Histogram Grayscale After"
                app.histo_5.Text = "-"
                app.histo_6.Text = "-"
        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ImageSelectorTab = uitab(app.TabGroup);
            app.ImageSelectorTab.Title = 'Image Selector';
            app.UIAxes2 = uiaxes(app.ImageSelectorTab);
            title(app.UIAxes2, 'Image')
            app.UIAxes2.Visible = 'off';
            app.ChooseImageDropDownLabel = uilabel(app.ImageSelectorTab);
            app.ChooseImageDropDownLabel.Text = 'Choose Image';
            app.ChooseImageDropDown = uidropdown(app.ImageSelectorTab);
            app.ChooseImageDropDown.Items = {'Image 1', 'Image 2', 'Image 3', 'Image 4'};
            app.ChooseImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseImageDropDownValueChanged, true);
            app.ChooseImageDropDown.Value = 'Image 1';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.histogram_1 = uiaxes(app.HistogramTab);
            title(app.histogram_1, 'Title')
            xlabel(app.histogram_1, 'X')
            ylabel(app.histogram_1, 'Y')
            zlabel(app.histogram_1, 'Z')
            app.histogram_1.Visible = 'off';
            app.histogram_2 = uiaxes(app.HistogramTab);
            xlabel(app.histogram_2, 'X')
            ylabel(app.histogram_2, 'Y')
            zlabel(app.histogram_2, 'Z')
            app.histogram_2.Visible = 'off';
            app.histogram_3 = uiaxes(app.HistogramTab);
            title(app.histogram_3, 'wew3e')
            app.histogram_3.Visible = 'off';
            app.ShowHistogramButton = uibutton(app.HistogramTab, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            app.Label = uilabel(app.HistogramTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.HistogramTab);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.HistogramTab);
            app.Label_3.Text = '';
            app.ImageEnhancementTab = uitab(app.TabGroup);
            app.ImageEnhancementTab.Title = 'Image Enhancement';
            app.h_before_1 = uiaxes(app.ImageEnhancementTab);
            title(app.h_before_1, 'Title')
            zlabel(app.h_before_1, 'Z')
            app.h_before_1.Visible = 'off';
            app.h_before_2 = uiaxes(app.ImageEnhancementTab);
            title(app.h_before_2, 'Title')
            zlabel(app.h_before_2, 'Z')
            app.h_before_2.Visible = 'off';
            app.h_before_3 = uiaxes(app.ImageEnhancementTab);
            title(app.h_before_3, 'Title')
            zlabel(app.h_before_3, 'Z')
            app.h_before_3.Visible = 'off';
            app.h_after_1 = uiaxes(app.ImageEnhancementTab);
            title(app.h_after_1, 'Title')
            ylabel(app.h_after_1, 'Y')
            zlabel(app.h_after_1, 'Z')
            app.h_after_1.Visible = 'off';
            app.h_after_2 = uiaxes(app.ImageEnhancementTab);
            title(app.h_after_2, 'Title')
            ylabel(app.h_after_2, 'Y')
            zlabel(app.h_after_2, 'Z')
            app.h_after_2.Visible = 'off';
            app.h_after_3 = uiaxes(app.ImageEnhancementTab);
            title(app.h_after_3, 'Title')
            ylabel(app.h_after_3, 'Y')
            zlabel(app.h_after_3, 'Z')
            app.h_after_3.Visible = 'off';
            app.img_1 = uiaxes(app.ImageEnhancementTab);
            title(app.img_1, 'Title')
            zlabel(app.img_1, 'Z')
            app.img_1.Visible = 'off';
            app.img_2 = uiaxes(app.ImageEnhancementTab);
            title(app.img_2, 'Title')
            zlabel(app.img_2, 'Z')
            app.img_2.Visible = 'off';
            app.ChooseEnhancementDropDownLabel = uilabel(app.ImageEnhancementTab);
            app.ChooseEnhancementDropDownLabel.Text = 'Choose Enhancement';
            app.ChooseEnhancementDropDown = uidropdown(app.ImageEnhancementTab);
            app.ChooseEnhancementDropDown.Items = {'Brightening', 'Log Transformation', 'Power Transformation', 'Contrast Stretching'};
            app.ChooseEnhancementDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseEnhancementDropDownValueChanged, true);
            app.ChooseEnhancementDropDown.Value = 'Brightening';
            app.param_1 = uieditfield(app.ImageEnhancementTab, 'numeric');
            app.param_1.ValueChangedFcn = createCallbackFcn(app, @param_1ValueChanged, true);
            app.param_1.Tag = 's';
            app.param_2 = uieditfield(app.ImageEnhancementTab, 'numeric');
            app.param_2.ValueChangedFcn = createCallbackFcn(app, @param_2ValueChanged, true);
            app.SubmitButton = uibutton(app.ImageEnhancementTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.formula = uilabel(app.ImageEnhancementTab);
            app.formula.Text = 'Label2';
            app.label_1 = uilabel(app.ImageEnhancementTab);
            app.label_1.Text = 'Label3';
            app.label_2 = uilabel(app.ImageEnhancementTab);
            app.label_2.Text = 'Label3';
            app.histo_1 = uilabel(app.ImageEnhancementTab);
            app.histo_1.Text = '';
            app.histo_2 = uilabel(app.ImageEnhancementTab);
            app.histo_2.Text = '';
            app.histo_3 = uilabel(app.ImageEnhancementTab);
            app.histo_3.Text = '';
            app.histo_4 = uilabel(app.ImageEnhancementTab);
            app.histo_4.Text = '';
            app.histo_5 = uilabel(app.ImageEnhancementTab);
            app.histo_5.Text = '';
            app.histo_6 = uilabel(app.ImageEnhancementTab);
            app.histo_6.Text = '';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.h_1 = uiaxes(app.HistogramEqualizationTab);
            title(app.h_1, 'Title')
            xlabel(app.h_1, 'X')
            ylabel(app.h_1, 'Y')
            zlabel(app.h_1, 'Z')
            app.h_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.h_2, 'Title')
            xlabel(app.h_2, 'X')
            ylabel(app.h_2, 'Y')
            zlabel(app.h_2, 'Z')
            app.h_3 = uiaxes(app.HistogramEqualizationTab);
            title(app.h_3, 'Title')
            xlabel(app.h_3, 'X')
            ylabel(app.h_3, 'Y')
            zlabel(app.h_3, 'Z')
            app.h_4 = uiaxes(app.HistogramEqualizationTab);
            title(app.h_4, 'Title')
            xlabel(app.h_4, 'X')
            ylabel(app.h_4, 'Y')
            zlabel(app.h_4, 'Z')
            app.h_5 = uiaxes(app.HistogramEqualizationTab);
            title(app.h_5, 'Title')
            xlabel(app.h_5, 'X')
            ylabel(app.h_5, 'Y')
            zlabel(app.h_5, 'Z')
            app.h_6 = uiaxes(app.HistogramEqualizationTab);
            title(app.h_6, 'Title')
            xlabel(app.h_6, 'X')
            ylabel(app.h_6, 'Y')
            zlabel(app.h_6, 'Z')
            app.image_before = uiaxes(app.HistogramEqualizationTab);
            title(app.image_before, 'Image Before')
            xlabel(app.image_before, 'X')
            ylabel(app.image_before, 'Y')
            zlabel(app.image_before, 'Z')
            app.image_after = uiaxes(app.HistogramEqualizationTab);
            title(app.image_after, 'Image After')
            xlabel(app.image_after, 'X')
            ylabel(app.image_after, 'Y')
            zlabel(app.image_after, 'Z')
            app.StartHisteqButton = uibutton(app.HistogramEqualizationTab, 'push');
            app.StartHisteqButton.ButtonPushedFcn = createCallbackFcn(app, @StartHisteqButtonPushed, true);
            app.StartHisteqButton.Text = 'Start';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end